classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        HomeTab                      matlab.ui.container.Tab
        Panel                        matlab.ui.container.Panel
        SelectMethodDropDownLabel    matlab.ui.control.Label
        SelectMethodDropDown         matlab.ui.control.DropDown
        FunctionIsEditFieldLabel     matlab.ui.control.Label
        FunctionIsEditField          matlab.ui.control.EditField
        X0EditFieldLabel             matlab.ui.control.Label
        X0EditField                  matlab.ui.control.NumericEditField
        X1EditFieldLabel             matlab.ui.control.Label
        X1EditField                  matlab.ui.control.NumericEditField
        EditFieldLabel               matlab.ui.control.Label
        maxErrorEditField            matlab.ui.control.NumericEditField
        MaxIterationsEditFieldLabel  matlab.ui.control.Label
        MaxIterationsEditField       matlab.ui.control.NumericEditField
        ModeSwitchLabel              matlab.ui.control.Label
        ModeSwitch                   matlab.ui.control.Switch
        CalculateButton              matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        InfoTab                      matlab.ui.container.Tab
        RootLabel                    matlab.ui.control.Label
        resultLabel                  matlab.ui.control.Label
    end


    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            %f = app.FunctionIsEditField.Value;
            f = str2func("@(x) " +app.FunctionIsEditField.Value);
            x0 = app.X0EditField.Value;
            x1 = app.X1EditField.Value;
            maxError = app.maxErrorEditField.Value;
            maxItr = app.MaxIterationsEditField.Value;
            mode = app.ModeSwitch.Value;
            disp(mode);
            
            switch (app.SelectMethodDropDown.Value)
                case 'Bisection'
                    
                case 'False-Position'
                    
                case 'Fixed Point'
                    [root, i, percision, time] = FPI(f, x0, maxError, maxItr, mode);  
                    app.resultLabel.Text = num2str(root);
                case 'Newton-Raphson'
                    [root] = NewtonRaphson(f,x0,maxItr,maxError);
                    app.resultLabel.Text = num2str(root);
                case 'Secant'
                    [root] = Secant(f,x0,x1,maxItr,maxError);
                    app.resultLabel.Text = num2str(root);            
                case 'Bierge Vieta' 
                    
                case 'General Algorithm'
                    [roots] = generalAlgorithm(f,x0,x1,maxError);
                    app.resultLabel.Text = num2str(roots);
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.Panel = uipanel(app.HomeTab);
            app.SelectMethodDropDownLabel = uilabel(app.Panel);
            app.SelectMethodDropDownLabel.Text = 'Select Method';
            app.SelectMethodDropDown = uidropdown(app.Panel);
            app.SelectMethodDropDown.Items = {'Bisection', 'False-Position', 'Fixed Point', 'Newton-Raphson', 'Secant', 'Bierge Vieta', 'General Algorithm'};
            app.SelectMethodDropDown.Value = 'Fixed Point';
            app.FunctionIsEditFieldLabel = uilabel(app.Panel);
            app.FunctionIsEditFieldLabel.Text = 'Function Is';
            app.FunctionIsEditField = uieditfield(app.Panel, 'text');
            app.FunctionIsEditField.Value = 'exp(-x) - x';
            app.X0EditFieldLabel = uilabel(app.Panel);
            app.X0EditFieldLabel.Text = 'X0';
            app.X0EditField = uieditfield(app.Panel, 'numeric');
            app.X1EditFieldLabel = uilabel(app.Panel);
            app.X1EditFieldLabel.Text = 'X1';
            app.X1EditField = uieditfield(app.Panel, 'numeric');
            app.EditFieldLabel = uilabel(app.Panel);
            app.EditFieldLabel.Text = 'ɛ';
            app.maxErrorEditField = uieditfield(app.Panel, 'numeric');
            app.maxErrorEditField.Value = 0.0001;
            app.MaxIterationsEditFieldLabel = uilabel(app.Panel);
            app.MaxIterationsEditFieldLabel.Text = 'Max Iterations';
            app.MaxIterationsEditField = uieditfield(app.Panel, 'numeric');
            app.MaxIterationsEditField.Limits = [0 Inf];
            app.MaxIterationsEditField.Value = 50;
            app.ModeSwitchLabel = uilabel(app.Panel);
            app.ModeSwitchLabel.Text = 'Mode';
            app.ModeSwitch = uiswitch(app.Panel, 'slider');
            app.ModeSwitch.Items = {'Quick', 'Step'};
            app.ModeSwitch.Value = 'Quick';
            app.CalculateButton = uibutton(app.Panel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.UIAxes = uiaxes(app.HomeTab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.InfoTab = uitab(app.TabGroup);
            app.InfoTab.Title = 'Info';
            app.RootLabel = uilabel(app.InfoTab);
            app.RootLabel.Text = 'Root = ';
            app.resultLabel = uilabel(app.InfoTab);
            app.resultLabel.Text = 'resultLabel';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end